classdef Mini_Project_1_Dropdown_Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        VLabel_2                 matlab.ui.control.Label
        ClearButton              matlab.ui.control.Button
        HzLabel                  matlab.ui.control.Label
        VLabel                   matlab.ui.control.Label
        unitDropDown             matlab.ui.control.DropDown
        TimeDivKnob              matlab.ui.control.Knob
        TimeDivKnobLabel         matlab.ui.control.Label
        FrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        AmplitudeEditField       matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        OffsetKnob               matlab.ui.control.Knob
        OffsetKnobLabel          matlab.ui.control.Label
        WaveformDropDown         matlab.ui.control.DropDown
        WaveformDropDownLabel    matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
        Switch                   matlab.ui.control.Switch
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeEditField, 
        % FrequencyEditField, OffsetKnob, Switch, TimeDivKnob, 
        % WaveformDropDown, unitDropDown
        function SwitchValueChanged(app, event)
            syms t A f real;
            A = app.AmplitudeEditField.Value;
            f = app.FrequencyEditField.Value;
            timediv = app.TimeDivKnob.Value;
            vx = app.WaveformDropDown.Value;
            offset = app.OffsetKnob.Value;
            unit = app.unitDropDown.Value;
            value = app.Switch.Value;

            switch value
                case 'Off'
                    app.Lamp.Color = [1 0 0];
                    cla(app.UIAxes);
                case 'On'
                    app.Lamp.Color = [0 1 0];
            end
            
            switch unit
                case 's'
                    timediv = timediv/10000;
                case 'ms'
                    timediv = timediv*(10^-3)/10000;
                case 'us'
                    timediv = timediv*(10^-6)/10000;
            end
            
            if strcmp(value,'On')
                switch vx
                    case 'Sine'
                  
                        T = 1 / f ;
                        N = 1000;
                        omega = 2*pi*f;
                        t  = ((0:N)/T)*timediv;
                        y = A * sin(omega*t);
                        plot( app.UIAxes, t , y + offset );
                        
                        
                    case 'Square'
                        
                        T = 1 / f ;
                        N = 1000;
                        omega = 2*pi*f;
                        t  = ((0:N)/T)*timediv;
                        y = 0 ;
                        k = 20000 ;
                        
                        for k = 1 : 2 : k
        
                            y = y  + ( ( A * 4 / pi ) * sin( k * omega *t ) / k );
                            
                        end
                        plot (app.UIAxes , t , y + offset );
                    
                    case 'Triangular'
                        
                        T = 1 / f;
                        N = 1000;
                        omega = 2*pi*f;
                        t  = ((0:N)/T )*timediv;
                        y = 0 ;
                        k = 200 ;
                        
                        for k = 1 : 2 : k
        
                            y = y  + A * (-4 / ( ( pi*k ) ^ 2 ) * (cos( k *omega* t)));
        
                        end
                        plot (app.UIAxes , t , y*2 + offset );
                    
                    case 'Sawtooth'
                        
                        T = 1 / f;
                        N = 1000;
                        omega = 2*pi*f;
                        t  = ((0:N)/T)*timediv;
                        y = 1/2 ;
                        k = 200 ;
                        for k = 1 : 1 : k
        
                            y = y + A *( -sin (k * omega * t) / ( k * pi ));
        
                        end
                        plot (app.UIAxes , t , y + offset );


                end
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.AmplitudeEditField.Value = 0;
            app.FrequencyEditField.Value = 0;
            app.TimeDivKnob.Value = 1;
            app.WaveformDropDown.Value = 'Sine';
            app.OffsetKnob.Value = 0;
            app.unitDropDown.Value = 's';
            cla(app.UIAxes);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Function generator')
            xlabel(app.UIAxes, 'Time/div')
            ylabel(app.UIAxes, 'Volt/div')
            zlabel(app.UIAxes, 'Z')
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.WaveformDropDownLabel = uilabel(app.UIFigure);
            app.WaveformDropDownLabel.Text = 'Waveform';
            app.WaveformDropDown = uidropdown(app.UIFigure);
            app.WaveformDropDown.Items = {'Sine', 'Square', 'Triangular', 'Sawtooth', ''};
            app.WaveformDropDown.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.WaveformDropDown.Value = 'Sine';
            app.OffsetKnobLabel = uilabel(app.UIFigure);
            app.OffsetKnobLabel.Text = 'Offset';
            app.OffsetKnob = uiknob(app.UIFigure, 'continuous');
            app.OffsetKnob.Limits = [-5 5];
            app.OffsetKnob.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.TimeDivKnobLabel = uilabel(app.UIFigure);
            app.TimeDivKnobLabel.Text = 'Time/Div';
            app.TimeDivKnob = uiknob(app.UIFigure, 'continuous');
            app.TimeDivKnob.Limits = [1 100];
            app.TimeDivKnob.MajorTicks = [1 10 20 30 40 50 60 70 80 90 100];
            app.TimeDivKnob.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.TimeDivKnob.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.TimeDivKnob.Value = 1;
            app.unitDropDown = uidropdown(app.UIFigure);
            app.unitDropDown.Items = {'s', 'ms', 'us'};
            app.unitDropDown.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.unitDropDown.Value = 's';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = 'V';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mini_Project_1_Dropdown_Menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end